classdef diabetes < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        AgeinyearsEditFieldLabel        matlab.ui.control.Label
        AgeinyearsEditField             matlab.ui.control.NumericEditField
        INPUTLabel                      matlab.ui.control.Label
        HBA1cinEditFieldLabel           matlab.ui.control.Label
        HBA1cinEditField                matlab.ui.control.NumericEditField
        FBSinmgdLEditFieldLabel         matlab.ui.control.Label
        FBSinmgdLEditField              matlab.ui.control.NumericEditField
        PPBSinmgdLEditFieldLabel        matlab.ui.control.Label
        PPBSinmgdLEditField             matlab.ui.control.NumericEditField
        Obesityinkgm2EditFieldLabel     matlab.ui.control.Label
        Obesityinkgm2EditField          matlab.ui.control.NumericEditField
        SlpHourinhoursEditFieldLabel    matlab.ui.control.Label
        SlpHourinhoursEditField         matlab.ui.control.NumericEditField
        StresslvlEditFieldLabel         matlab.ui.control.Label
        StresslvlEditField              matlab.ui.control.NumericEditField
        SenMedExamindaysEditFieldLabel  matlab.ui.control.Label
        SenMedExamindaysEditField       matlab.ui.control.NumericEditField
        PhysicalExerciseinminutesEditFieldLabel  matlab.ui.control.Label
        PhysicalExerciseinminutesEditField  matlab.ui.control.NumericEditField
        Age_lvl                         matlab.ui.control.LinearGauge
        HBA1c_lvl                       matlab.ui.control.LinearGauge
        FBS_lvl                         matlab.ui.control.LinearGauge
        PPBS_lvl                        matlab.ui.control.LinearGauge
        obesity_lvl                     matlab.ui.control.LinearGauge
        slphr_lvl                       matlab.ui.control.LinearGauge
        strlvl_lvl                      matlab.ui.control.LinearGauge
        senmedexm_lvl                   matlab.ui.control.LinearGauge
        phyexr_lvl                      matlab.ui.control.LinearGauge
        SeeYourResultButton             matlab.ui.control.StateButton
        Lamp                            matlab.ui.control.Lamp
        FuzzySetLabel                   matlab.ui.control.Label
        a                               matlab.ui.control.Label
        b                               matlab.ui.control.Label
        c                               matlab.ui.control.Label
        d                               matlab.ui.control.Label
        e                               matlab.ui.control.Label
        f                               matlab.ui.control.Label
        g                               matlab.ui.control.Label
        h                               matlab.ui.control.Label
        i                               matlab.ui.control.Label
        result                          matlab.ui.control.Label
        ExpertKnowledgebaseInformationSystemforDiabetesDetectionLabel  matlab.ui.control.Label
        DevelopedbyRajkrishnaMondalLabel  matlab.ui.control.Label
        s1                              matlab.ui.control.Slider
        s2                              matlab.ui.control.Slider
        s3                              matlab.ui.control.Slider
        s4                              matlab.ui.control.Slider
        s5                              matlab.ui.control.Slider
        s6                              matlab.ui.control.Slider
        s7                              matlab.ui.control.Slider
        s8                              matlab.ui.control.Slider
        s9                              matlab.ui.control.Slider
    end

    methods (Access = private)

        % Value changed function: AgeinyearsEditField, 
        % SeeYourResultButton
        function SeeYourResultButtonValueChanged(app, event)
            %value = app.SeeYourResultButton.Value;
            
            age=app.AgeinyearsEditField.Value;  app.Age_lvl.Value=age;
            hba1c=app.HBA1cinEditField.Value; app.HBA1c_lvl.Value=hba1c;
            fbs=app.FBSinmgdLEditField.Value; app.FBS_lvl.Value=fbs;
            ppbs=app.PPBSinmgdLEditField.Value;   app.PPBS_lvl.Value=ppbs;
            obesity=app.Obesityinkgm2EditField.Value; app.obesity_lvl.Value=obesity;
            slphr=app.SlpHourinhoursEditField.Value;   app.slphr_lvl.Value=slphr;
            strlvl=app.StresslvlEditField.Value;    app.strlvl_lvl.Value=strlvl;
            senmed=app.SenMedExamindaysEditField.Value;   app.senmedexm_lvl.Value=senmed;
            phyexr=app.PhysicalExerciseinminutesEditField.Value; app.phyexr_lvl.Value=phyexr;
            
            index={'a','b','c','d','e','f','g','h','i'};
            ip={["Young","Middle Aged","Aged","Very Aged","Old"],...
                ["Low","Normal","Pre","High","Very High"],...
                ["Very Low","Low","Normal","High","Very High"],...
                ["Very Low","Low","Normal","High","Very High"],...
                ["Normal","Over Weight","Obese","Extremely Obese"],...
                ["Very Low","Low","Normal","High"],...
                ["Low","Moderate","Active","Hyperactive"],...
                ["Weekly","Fortnightly","Monthly","Half Yearly","Yearly"],...
                ["Little Effective","Slightly Effective","Very Effective","Very Very Effective","Extremely Effective"]};
            
            data=[age,hba1c,fbs,ppbs,obesity,slphr,strlvl,senmed,phyexr];
            [x,n,p,t1,t2]=Diabates(data);
            for i=1:length(index)
            app.(index{i}).Text=[ip{i}(x(i,2)) ,num2str(x(i,1))];
            end
            
            [~,a]=max([n,p,t1,t2]);
            if a==1
                app.result.Text='Normal';
                app.Lamp.Color=[0 1 0];
            elseif a==2
                app.result.Text='Pre-Diabetes';
                app.Lamp.Color=[1 1 0];
            elseif a==3
                app.result.Text='Type-1';
                app.Lamp.Color=[1 0 0];
            elseif a==4
                app.result.Text='Type-2';
                app.Lamp.Color=[1 0 0];
            end 
        end

        % Value changed function: s1
        function s1ValueChanged(app, event)
            app.Age_lvl.Value = app.s1.Value;  
            app.AgeinyearsEditField.Value=app.s1.Value;    
        end

        % Value changed function: s2
        function s2ValueChanged(app, event)
            app.HBA1c_lvl.Value = app.s2.Value;  
            app.HBA1cinEditField.Value=app.s2.Value;  
        end

        % Value changed function: s3
        function s3ValueChanged(app, event)
            app.FBS_lvl.Value = app.s3.Value;  
            app.FBSinmgdLEditField.Value=app.s3.Value;
        end

        % Value changed function: s4
        function s4ValueChanged(app, event)
            app.PPBS_lvl.Value = app.s4.Value;  
            app.PPBSinmgdLEditField.Value=app.s4.Value;
        end

        % Value changed function: s5
        function s5ValueChanged(app, event)
            app.obesity_lvl.Value = app.s5.Value;  
            app.Obesityinkgm2EditField.Value=app.s5.Value;
        end

        % Value changed function: s6
        function s6ValueChanged(app, event)
            app.slphr_lvl.Value = app.s6.Value;  
            app.SlpHourinhoursEditField.Value=app.s6.Value;
        end

        % Value changed function: s7
        function s7ValueChanged(app, event)
            app.strlvl_lvl.Value = app.s7.Value;  
            app.StresslvlEditField.Value=app.s7.Value;
        end

        % Value changed function: s8
        function s8ValueChanged(app, event)
            app.senmedexm_lvl.Value = app.s8.Value;  
            app.SenMedExamindaysEditField.Value=app.s8.Value;
        end

        % Value changed function: s9
        function s9ValueChanged(app, event)
            app.phyexr_lvl.Value = app.s9.Value;  
            app.PhysicalExerciseinminutesEditField.Value=app.s9.Value;
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.AgeinyearsEditFieldLabel = uilabel(app.UIFigure);
            app.AgeinyearsEditFieldLabel.Text = {'Age'; '(in years)'};
            app.AgeinyearsEditField = uieditfield(app.UIFigure, 'numeric');
            app.AgeinyearsEditField.Limits = [0 110];
            app.AgeinyearsEditField.ValueChangedFcn = createCallbackFcn(app, @SeeYourResultButtonValueChanged, true);
            app.INPUTLabel = uilabel(app.UIFigure);
            app.INPUTLabel.Text = {'I'; 'N'; 'P'; 'U'; 'T'};
            app.HBA1cinEditFieldLabel = uilabel(app.UIFigure);
            app.HBA1cinEditFieldLabel.Text = {'HBA1c'; '(in %)'};
            app.HBA1cinEditField = uieditfield(app.UIFigure, 'numeric');
            app.HBA1cinEditField.Limits = [0 15];
            app.FBSinmgdLEditFieldLabel = uilabel(app.UIFigure);
            app.FBSinmgdLEditFieldLabel.Text = {'FBS'; '(in mg/dL)'};
            app.FBSinmgdLEditField = uieditfield(app.UIFigure, 'numeric');
            app.FBSinmgdLEditField.Limits = [0 250];
            app.PPBSinmgdLEditFieldLabel = uilabel(app.UIFigure);
            app.PPBSinmgdLEditFieldLabel.Text = {'PPBS'; '(in mg/dL)'};
            app.PPBSinmgdLEditField = uieditfield(app.UIFigure, 'numeric');
            app.PPBSinmgdLEditField.Limits = [0 300];
            app.Obesityinkgm2EditFieldLabel = uilabel(app.UIFigure);
            app.Obesityinkgm2EditFieldLabel.Text = {'Obesity'; '(in kg/m2)'};
            app.Obesityinkgm2EditField = uieditfield(app.UIFigure, 'numeric');
            app.Obesityinkgm2EditField.Limits = [10 60];
            app.Obesityinkgm2EditField.Value = 10;
            app.SlpHourinhoursEditFieldLabel = uilabel(app.UIFigure);
            app.SlpHourinhoursEditFieldLabel.Text = {'Slp Hour'; '(in hours)'};
            app.SlpHourinhoursEditField = uieditfield(app.UIFigure, 'numeric');
            app.SlpHourinhoursEditField.Limits = [0 15];
            app.StresslvlEditFieldLabel = uilabel(app.UIFigure);
            app.StresslvlEditFieldLabel.Text = 'Stress lvl';
            app.StresslvlEditField = uieditfield(app.UIFigure, 'numeric');
            app.StresslvlEditField.Limits = [0 30];
            app.SenMedExamindaysEditFieldLabel = uilabel(app.UIFigure);
            app.SenMedExamindaysEditFieldLabel.Text = {'Sen Med Exam'; '(in days)'};
            app.SenMedExamindaysEditField = uieditfield(app.UIFigure, 'numeric');
            app.SenMedExamindaysEditField.Limits = [0 250];
            app.PhysicalExerciseinminutesEditFieldLabel = uilabel(app.UIFigure);
            app.PhysicalExerciseinminutesEditFieldLabel.Text = {'Physical Exercise'; '(in minutes)'};
            app.PhysicalExerciseinminutesEditField = uieditfield(app.UIFigure, 'numeric');
            app.PhysicalExerciseinminutesEditField.Limits = [0 180];
            app.Age_lvl = uigauge(app.UIFigure, 'linear');
            app.Age_lvl.Limits = [0 110];
            app.Age_lvl.MajorTicks = [0 10 20 30 40 50 60 70 80 95 110];
            app.Age_lvl.MajorTickLabels = {'0', 'Young', '20', 'Mid Aged', '40', 'Aged', '60', 'Very Aged', '80', 'Old', '110'};
            app.HBA1c_lvl = uigauge(app.UIFigure, 'linear');
            app.HBA1c_lvl.Limits = [0 15];
            app.HBA1c_lvl.MajorTicks = [0 2 4 4.85 5.975 7.125 8 11.5 15];
            app.HBA1c_lvl.MajorTickLabels = {'0', 'Low', '4', 'Normal', 'Pre', 'High', '8', 'very High', '15'};
            app.FBS_lvl = uigauge(app.UIFigure, 'linear');
            app.FBS_lvl.Limits = [0 250];
            app.FBS_lvl.MajorTicks = [0 17.5 35 47.5 60 82.5 105 152.5 200 225 250];
            app.FBS_lvl.MajorTickLabels = {'0', 'VL', '35', 'L', '60', 'N', '105', 'H', '200', 'VH', '250'};
            app.PPBS_lvl = uigauge(app.UIFigure, 'linear');
            app.PPBS_lvl.Limits = [0 300];
            app.PPBS_lvl.MajorTicks = [0 17.5 35 47.5 60 100 140 195 250 275 300];
            app.PPBS_lvl.MajorTickLabels = {'0', 'VL', '35', 'L', '60', 'N', '140', 'H', '250', 'VH', '300'};
            app.obesity_lvl = uigauge(app.UIFigure, 'linear');
            app.obesity_lvl.Limits = [10 60];
            app.obesity_lvl.MajorTicks = [10 15 20 25 30 35 40 50 60];
            app.obesity_lvl.MajorTickLabels = {'10', 'Normal', '20', 'Over Weight', '30', 'Obese', '40', 'Extremely Obese', '60'};
            app.obesity_lvl.Value = 10;
            app.slphr_lvl = uigauge(app.UIFigure, 'linear');
            app.slphr_lvl.Limits = [0 15];
            app.slphr_lvl.MajorTicks = [0 2 4 5 6 7 8 11.5 15];
            app.slphr_lvl.MajorTickLabels = {'0', 'VL', '4', 'L', '6', 'N', '8', 'H', '15'};
            app.strlvl_lvl = uigauge(app.UIFigure, 'linear');
            app.strlvl_lvl.Limits = [0 30];
            app.strlvl_lvl.MajorTicks = [0 3 6 10.5 15 17.5 20 25 30];
            app.strlvl_lvl.MajorTickLabels = {'0', 'LOW', '6', 'MODERATE', '15', 'ACTIVE', '20', 'HYPERACTIVE', '30'};
            app.senmedexm_lvl = uigauge(app.UIFigure, 'linear');
            app.senmedexm_lvl.Limits = [0 250];
            app.senmedexm_lvl.MajorTicks = [0 3 6 10.5 15 21.5 28 104 180 215 250];
            app.senmedexm_lvl.MajorTickLabels = {'0', 'W', '6', 'F', '15', 'M', '28', 'H', '180', 'Y', '250'};
            app.phyexr_lvl = uigauge(app.UIFigure, 'linear');
            app.phyexr_lvl.Limits = [0 180];
            app.phyexr_lvl.MajorTicks = [0 4 8 16.5 25 35 45 55 65 122.5 180];
            app.phyexr_lvl.MajorTickLabels = {'0', 'LE', '8', 'SE', '25', 'VE', '45', 'VVE', '65', 'EE', '180'};
            app.SeeYourResultButton = uibutton(app.UIFigure, 'state');
            app.SeeYourResultButton.ValueChangedFcn = createCallbackFcn(app, @SeeYourResultButtonValueChanged, true);
            app.SeeYourResultButton.Text = 'See Your Result';
            app.Lamp = uilamp(app.UIFigure);
            app.FuzzySetLabel = uilabel(app.UIFigure);
            app.FuzzySetLabel.Text = 'Fuzzy Set';
            app.a = uilabel(app.UIFigure);
            app.a.Text = '';
            app.b = uilabel(app.UIFigure);
            app.b.Text = '';
            app.c = uilabel(app.UIFigure);
            app.c.Text = '';
            app.d = uilabel(app.UIFigure);
            app.d.Text = '';
            app.e = uilabel(app.UIFigure);
            app.e.Text = '';
            app.f = uilabel(app.UIFigure);
            app.f.Text = '';
            app.g = uilabel(app.UIFigure);
            app.g.Text = '';
            app.h = uilabel(app.UIFigure);
            app.h.Text = '';
            app.i = uilabel(app.UIFigure);
            app.i.Text = '';
            app.result = uilabel(app.UIFigure);
            app.result.Text = '';
            app.ExpertKnowledgebaseInformationSystemforDiabetesDetectionLabel = uilabel(app.UIFigure);
            app.ExpertKnowledgebaseInformationSystemforDiabetesDetectionLabel.Text = 'Expert Knowledge base Information System for Diabetes Detection';
            app.DevelopedbyRajkrishnaMondalLabel = uilabel(app.UIFigure);
            app.DevelopedbyRajkrishnaMondalLabel.Text = 'Developed by Rajkrishna Mondal';
            app.s1 = uislider(app.UIFigure);
            app.s1.Limits = [0 110];
            app.s1.MajorTicks = [];
            app.s1.ValueChangedFcn = createCallbackFcn(app, @s1ValueChanged, true);
            app.s1.MinorTicks = [];
            app.s2 = uislider(app.UIFigure);
            app.s2.Limits = [0 15];
            app.s2.MajorTicks = [];
            app.s2.ValueChangedFcn = createCallbackFcn(app, @s2ValueChanged, true);
            app.s2.MinorTicks = [];
            app.s3 = uislider(app.UIFigure);
            app.s3.Limits = [0 250];
            app.s3.MajorTicks = [];
            app.s3.ValueChangedFcn = createCallbackFcn(app, @s3ValueChanged, true);
            app.s3.MinorTicks = [];
            app.s4 = uislider(app.UIFigure);
            app.s4.Limits = [0 300];
            app.s4.MajorTicks = [];
            app.s4.ValueChangedFcn = createCallbackFcn(app, @s4ValueChanged, true);
            app.s4.MinorTicks = [];
            app.s5 = uislider(app.UIFigure);
            app.s5.Limits = [10 60];
            app.s5.MajorTicks = [];
            app.s5.ValueChangedFcn = createCallbackFcn(app, @s5ValueChanged, true);
            app.s5.MinorTicks = [];
            app.s5.Value = 10;
            app.s6 = uislider(app.UIFigure);
            app.s6.Limits = [0 15];
            app.s6.MajorTicks = [];
            app.s6.ValueChangedFcn = createCallbackFcn(app, @s6ValueChanged, true);
            app.s6.MinorTicks = [];
            app.s7 = uislider(app.UIFigure);
            app.s7.Limits = [0 30];
            app.s7.MajorTicks = [];
            app.s7.ValueChangedFcn = createCallbackFcn(app, @s7ValueChanged, true);
            app.s7.MinorTicks = [];
            app.s8 = uislider(app.UIFigure);
            app.s8.Limits = [0 250];
            app.s8.MajorTicks = [];
            app.s8.ValueChangedFcn = createCallbackFcn(app, @s8ValueChanged, true);
            app.s8.MinorTicks = [];
            app.s9 = uislider(app.UIFigure);
            app.s9.Limits = [0 180];
            app.s9.MajorTicks = [];
            app.s9.ValueChangedFcn = createCallbackFcn(app, @s9ValueChanged, true);
            app.s9.MinorTicks = [];
        end
    end

    methods (Access = public)

        % Construct app
        function app = diabetes

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end